classdef Reto_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel                         matlab.ui.container.Panel
        EPValLabel                    matlab.ui.control.Label
        Label_7                       matlab.ui.control.Label
        VelocidadLabel_2              matlab.ui.control.Label
        Label_6                       matlab.ui.control.Label
        Label_5                       matlab.ui.control.Label
        Label_4                       matlab.ui.control.Label
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        EnergiaperdidaLabel           matlab.ui.control.Label
        yLabel                        matlab.ui.control.Label
        xLabel                        matlab.ui.control.Label
        PosicinLabel                  matlab.ui.control.Label
        VelocidadmaximaLabel          matlab.ui.control.Label
        DuracinLabel                  matlab.ui.control.Label
        TiempoActualLabel             matlab.ui.control.Label
        TiempodeinicioLabel           matlab.ui.control.Label
        ResultadosLabel               matlab.ui.control.Label
        SimulacindeFormula1Label      matlab.ui.control.Label
        DatosdeEntradaPanel           matlab.ui.container.Panel
        AlertaLabel                   matlab.ui.control.Label
        CorrerSimulacionButton        matlab.ui.control.Button
        CoeficientedefrccinEditField  matlab.ui.control.NumericEditField
        CoeficientedefrccinEditFieldLabel  matlab.ui.control.Label
        MasaEditField                 matlab.ui.control.NumericEditField
        MasaEditFieldLabel            matlab.ui.control.Label
        VelocidadEditField            matlab.ui.control.NumericEditField
        VelocidadLabel                matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        a = 0.00010262;
        b = -0.040327;
        c = 3.2342;
        d = 261.5886;
        
        f1 = @(x) a * x.^3 + b * x.^2 + c * x + d;
        f2 = @(x) 3 * a * x.^2 + 2 * b * x + c;
        f3 = @(x) 6 * a * x + 2 * b;        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Funcion que se ejecuta al momento de crear la app
            
            %Coeficientes para componer la funcion polinomial
            app.a = 0.00010262; 
            app.b = -0.040327;
            app.c = 3.2342;
            app.d = 261.5886;
            
            %Funcion original, primera y segunda derivada
            app.f1 = @(x) app.a * x.^3 + app.b * x.^2 + app.c * x + app.d;
            app.f2 = @(x) 3 * app.a * x.^2 + 2 * app.b * x + app.c;
            app.f3 = @(x) 6 * app.a * x + 2 * app.b;
            
            %Vectores para plotear las gradas por seccion
            g_x = [];
            g_y = []; %Seccion 1
            g_x_2 = [];
            g_y_2 = []; %Seccion 2
            g_x_3 = [];
            g_y_3 = []; %Seccion 3
            
            %Ciclo para evaluar las gradas
            for i = 10:1:270           
     
                %Calculo de la funcion tangencial 
                m = app.f2(i);
                tang = @(x) (((-1/m)*(x-i)) + app.f1(i));
                
                %Evaluacion de la funcion tangencial
                di=0;
                p = 0;
                while p < 20
                   di = di - 0.1;
                   %Funcion de distancia entre puntos, para plotear a 20m
                   p = sqrt(((i-(i-di))^2)+((app.f1(i)-tang(i-di)))^2);
                end
                
                %Llenamos los vectores de las evaluaciones
                if i < 30 
                    g_x = [g_x, i+di];
                    g_y = [g_y, tang(i+di)];
                elseif (i > 66 && i < 195 )
                    g_x_2 = [g_x_2, i+di];
                    g_y_2 = [g_y_2, tang(i+di)];
                elseif i > 231
                    g_x_3 = [g_x_3, i+di];
                    g_y_3 = [g_y_3, tang(i+di)];
                end
            end
            
            %Ploteamos todas las funciones
            hold(app.UIAxes, "on");
            axis(app.UIAxes, "equal")
            fplot(app.UIAxes,app.f1,[10,260],"LineWidth",12,"color","#000000");
            fplot(app.UIAxes,app.f1,[10,260],"LineWidth",1,"color","#ffff00","LineStyle","--");            
            plot(app.UIAxes,g_x,g_y, "LineWidth",3,"color","#ff0000")
            plot(app.UIAxes,g_x_2,g_y_2, "LineWidth",3,"color","#ff0000")
            plot(app.UIAxes,g_x_3,g_y_3, "LineWidth",3,"color","#ff0000")
        end

        % Button pushed function: CorrerSimulacionButton
        function CorrerSimulacionButtonPushed(app, event)
            %Funcion que ejecuta al presionar el boton de correr simulacion
            
            %Reiniciamos labels 
            app.AlertaLabel.Visible = false;
            app.EPValLabel.Text = "";
            app.AlertaLabel.Text = "Alerta!!";
            
            %Pasamos los inputs de la GUI a variables
            velocidad = app.VelocidadEditField.Value / 10;
            masa = app.MasaEditField.Value;
            coef_friccion = app.CoeficientedefrccinEditField.Value;
            g = 9.81;
            
            %PLoteamos el tiempo
            tiempo_inicial = datetime('now','TimeZone','local','Format','HH:mm:ss');
            app.Label_3.Text = char(tiempo_inicial);
            
            %Evaluamos el maximo y minimo
            xMax = (- app.b - sqrt(app.b^2 - 3  *app.a *app.c)) / (3 * app.a);
            xMin = (- app.b + sqrt(app.b^2 - 3 * app.a*app.c)) / (3 * app.a);
            yMax = app.f1(xMax);
            yMin = app.f1(xMin);
  
            %%Calculamos la curvatura en el maximo y minimo
            r_curv = @(x) ((1 + (app.f2(x).^2)).^(3/2))/abs(app.f3(x));
            c_max = r_curv(xMax);
            c_min = r_curv(xMin);
            
            %Calculamos los intervalos de la zona critica
            i_crit = [0];
            for i = 0:1:280
                %Si el radio cambia de mas a menos de 50
                if (r_curv(i) > 50 && r_curv(i+1) < 50)
                    i_crit = [i_crit, (i+1)];
                %Si el radio cambia de menos a mas
                elseif (r_curv(i) < 50 && r_curv(i+1) > 50)
                    disp(" ")
                    i_crit = [i_crit, (i+1)];
                end
            end
            
            %Asignamos variables para la condicion de derrape
            f = app.f1;
            derrapo = false;
            contador = 0;
            fs = coef_friccion * g * masa;
            
            for i = 10:0.1:260 
                %Calculamos la velocidad maxima segun la curvatura
              Velocidad_Max = (sqrt(r_curv(i)*coef_friccion*g) / 10);
              
              if derrapo == false
                  %Evaluamos si ha derrapado o no
                  if velocidad > Velocidad_Max
                    %Cambiamos la trayectoria de polinomial a tangencial
                    f = @(x) (app.f2(i)*(x-i)) + app.f1(i);
                    
                    %Alertamos al usuario
                    app.AlertaLabel.Visible = true;
                
                    derrapo = true;
                  end
              else
                  %Contador del avance de la distancia de derrape
                  contador = contador + 10;
              end
              
              if contador == 10
                  x_derrape = i;
              end
             
              %Plotear la posición actual
              ax = plot(app.UIAxes, i, f(i), ".", "MarkerSize", 30,"Color","red");
              pause(0.001);
              
              %Si ya terminó, no borrar el punto
              if ~(contador>=(0.5 * masa * (velocidad^2)) && derrapo == true)
                delete(ax)
              else
                %Calculamos la energía perdida 
                x_derrape_final = i;
                app.EPValLabel.Text = num2str((0.5 * masa * (velocidad^2)*x_derrape_final)/1000) + " kJ";
                
                break;
              end
              
              %Plotear tiempo final 
              time = datetime('now','TimeZone','local','Format','HH:mm:ss');
              app.Label_4.Text = char(time);
              
              %Actualizar labels
              app.Label_5.Text = char(time - tiempo_inicial);
              app.Label_7.Text = num2str((velocidad*10)) + "m/s";
              app.Label_6.Text = num2str(Velocidad_Max*10) + "m/s";
              app.Label.Text = num2str(i);
              app.Label_2.Text = num2str(f(i));
            end
            
            %Mensaje de alerta
            if exist("x_derrape","var")
                app.AlertaLabel.Text = "El carro se derrapo en (" + num2str(x_derrape) + ", " + num2str(app.f1(x_derrape)) + ") porque iba a " + num2str((velocidad)*10) + "m/s mientras el limite de velocidad en esa parte de la curva es " + num2str(sqrt(r_curv(x_derrape)*coef_friccion*g)) + " m/s";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Pista')
            xlabel(app.UIAxes, 'X (metros)')
            ylabel(app.UIAxes, 'Y (metros)')
            zlabel(app.UIAxes, 'Z')
            app.DatosdeEntradaPanel = uipanel(app.UIFigure);
            app.DatosdeEntradaPanel.Title = 'Datos de Entrada';
            app.VelocidadLabel = uilabel(app.DatosdeEntradaPanel);
            app.VelocidadLabel.Text = 'Velocidad:';
            app.VelocidadEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.MasaEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.MasaEditFieldLabel.Text = 'Masa:';
            app.MasaEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.CoeficientedefrccinEditFieldLabel = uilabel(app.DatosdeEntradaPanel);
            app.CoeficientedefrccinEditFieldLabel.Text = 'Coeficiente de frcción:';
            app.CoeficientedefrccinEditField = uieditfield(app.DatosdeEntradaPanel, 'numeric');
            app.CorrerSimulacionButton = uibutton(app.DatosdeEntradaPanel, 'push');
            app.CorrerSimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @CorrerSimulacionButtonPushed, true);
            app.CorrerSimulacionButton.Text = 'Correr Simulacion';
            app.AlertaLabel = uilabel(app.DatosdeEntradaPanel);
            app.AlertaLabel.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.SimulacindeFormula1Label = uilabel(app.Panel);
            app.SimulacindeFormula1Label.Text = 'Simulación de Formula1';
            app.ResultadosLabel = uilabel(app.Panel);
            app.ResultadosLabel.Text = 'Resultados';
            app.TiempodeinicioLabel = uilabel(app.Panel);
            app.TiempodeinicioLabel.Text = 'Tiempo de inicio:';
            app.TiempoActualLabel = uilabel(app.Panel);
            app.TiempoActualLabel.Text = 'Tiempo Actual:';
            app.DuracinLabel = uilabel(app.Panel);
            app.DuracinLabel.Text = 'Duración:';
            app.VelocidadmaximaLabel = uilabel(app.Panel);
            app.VelocidadmaximaLabel.Text = 'Velocidad maxima: ';
            app.PosicinLabel = uilabel(app.Panel);
            app.PosicinLabel.Text = 'Posición';
            app.xLabel = uilabel(app.Panel);
            app.xLabel.Text = 'x:';
            app.yLabel = uilabel(app.Panel);
            app.yLabel.Text = 'y:';
            app.EnergiaperdidaLabel = uilabel(app.Panel);
            app.EnergiaperdidaLabel.Text = 'Energia perdida:';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.Panel);
            app.Label_6.Text = '';
            app.VelocidadLabel_2 = uilabel(app.Panel);
            app.VelocidadLabel_2.Text = 'Velocidad:';
            app.Label_7 = uilabel(app.Panel);
            app.Label_7.Text = '';
            app.EPValLabel = uilabel(app.Panel);
            app.EPValLabel.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Reto_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end